classdef CloneFit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        experimental       matlab.ui.container.Panel
        loadexp            matlab.ui.control.Button
        numerical          matlab.ui.container.Panel
        UIAxesnum          matlab.ui.control.UIAxes
        LabelSlider        matlab.ui.control.Label
        Slidert            matlab.ui.control.Slider
        LabelSlider3       matlab.ui.control.Label
        Sliderh            matlab.ui.control.Slider
        LabelSlider4       matlab.ui.control.Label
        Sliderlambda       matlab.ui.control.Slider
        Label              matlab.ui.control.Label
        Slidera            matlab.ui.control.Slider
        TextArea           matlab.ui.control.TextArea
        TextArea_2         matlab.ui.control.TextArea
        Inference          matlab.ui.container.Panel
        Buttoninference    matlab.ui.control.Button
        Panel2             matlab.ui.container.Panel
        CheckBoxfixh       matlab.ui.control.CheckBox
        CheckBoxfixa       matlab.ui.control.CheckBox
        CheckBoxfixlambda  matlab.ui.control.CheckBox
        Panel              matlab.ui.container.Panel
        Buttonexportnum    matlab.ui.control.Button
        Buttonexportexp    matlab.ui.control.Button
    end


    %% Properties used throuhout the app. Accesible by app.(property) from other functions
    properties (Access = private)
        % Model  parameters
        a = 0;
        h = 0;
        lambda = 0.1;
        
        % Current numerical data
        num_data;
                
        % Time parameter (days)
        t = 100;
        
        % Experimental data, days of experimental data and source file
        p2d_exp;
        days;
        file;                            % Experimental source file
        
        % Handles to figures, legends and plots
        exp_fig_axis;                    % Handles to experimental figures   
        exp_fig_labels;                  % Handles to labels of experimental figures     
        num_fits;                        % Handles to numerical data in experimental figures   
        text_inference;                  % Handles to text boxes with inference results
        
        % Fixed parameters and number of fixed parameters for inference
        fixh; 
        fixa; 
        fixlambda;
        Nfixed = 0;
    end

    %% General Methods used throughout the app.
    methods (Access = private)
        
        % Updates numerical data in numerical figure to current parameter and time values
        function update_numfig(app)
            % Get data to plot
            id = (app.num_data.a==app.a & app.num_data.h==app.h & app.num_data.t==app.t & app.num_data.lambda==app.lambda);
            % Plot data
            p2d = app.num_data.p2d(id,:);
            plot(p2d,'o-k','Parent',app.UIAxesnum);
        end
        
        % Discretize parameter values to closest value available in data after update of a parameter with slide bar
        function find_discrete(app,vari)
            xs = app.(vari);
            xs_all = app.num_data.(vari);
            [~,med_xs_id]=min(abs(xs_all-xs));
            app.(vari) = xs_all(med_xs_id);
        end
        
        % Update slidde bar tick values and limits to available numerical data. (Slide bars are created before app launches).
        function update_slider(app,vari)
            % Get range of variable in current data set
            xs=unique(app.num_data.(vari));
            [~,med_xs_id]=min(abs(xs-median(xs)));
            major_ticks=[min(xs) xs(med_xs_id(1)) max(xs)];
            minor_ticks=sort(xs);
            % Adjust slider limits, ticks, value
            vari_long=strcat('Slider',vari);
            app.(vari_long).Limits=[min(xs) max(xs)];
            app.(vari_long).MajorTicks=major_ticks;
            app.(vari_long).MinorTicks=minor_ticks;
            app.(vari_long).Value=app.(vari);
        end

        % Add numerical data to experimental figures
        function update_num_in_expfig(app)
            bins=2.^[0:15];
            if ~isempty(app.exp_fig_axis)
                if ~isempty(app.num_fits)
                    delete(app.num_fits);
                    app.num_fits=gobjects(0);
                end
                id= (app.num_data.a==app.a & app.num_data.lambda==app.lambda & app.num_data.h==app.h);
                if sum(id)==numel(unique(app.num_data.t))
                    for i=1:numel(app.days)
                        hold(app.exp_fig_axis(i),'on');
                        ll=loglog(app.exp_fig_axis(i),bins,app.num_data.p2d(id & app.num_data.t==app.days(i),:));
                        ll.Color='r';
                        ll.LineStyle='-';
                        app.num_fits(i)=ll;
                    end
                end
            end
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Operations to execute at the startup of the app
            
            % Load surface growth data as default start
            app.num_data=load('app_num_data_cgs181026');    
            app.Buttoninference.Enable='off';
            app.Buttonexportexp.Enable='off';
            app.Buttonexportnum.Enable='on';
            
            % Initialize sliders: lambda
            find_discrete(app,'lambda')
            update_slider(app,'lambda')
            
            % Initialize sliders: time
            find_discrete(app,'t')
            update_slider(app,'t')
            
            % Initialize sliders: h
            find_discrete(app,'h')
            update_slider(app,'h')
            
            % Initialize sliders: a
            find_discrete(app,'a')
            update_slider(app,'a')
                        
            % Initialize figure
            app.UIAxesnum.XLim=[1 size(app.num_data.p2d,2)];
            ymin=10^(-6);
            app.UIAxesnum.YLim=[ymin 1];
            app.UIAxesnum.YScale = 'Log';
            app.UIAxesnum.YTick = [ymin 10^round(log10(ymin)/2) 1];
            app.UIAxesnum.XTick = log2([10^0 10^2 10^4]);
            app.UIAxesnum.XTickLabel = [10^0 10^2 10^4];
            
            % Initialize plot
            app.h=0.333;                % random start value of h
            find_discrete(app,'h');
            update_numfig(app);
            
        end

        % Button pushed function: loadexp
        function loadexpButtonPushed(app, event)
            %% Operations to execute after the "load experimental" button is pressed.
            
            % Load experimental data
            [app.file,path]=uigetfile('*.xl*');
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            [data,headers]=xlsread(strcat(path,app.file));
            [~,Ntimes]=size(data);
            bins=2.^[0:16];
            app.p2d_exp=zeros(Ntimes,numel(bins)-1);
            
            % Delete handles to numerical lines
            if ~isempty(app.num_fits)
                app.num_fits=[];
            end
            
            % Delete existing experimental figures
            if ~isempty(app.exp_fig_axis)
                delete(app.exp_fig_axis);
                app.exp_fig_axis=gobjects(0);
                delete(app.exp_fig_labels);
                app.exp_fig_labels=[];
            end
            
            % Create plots of experimental data with legends as textareas above plots
            start_hor=130;
            d_hor=(app.experimental.Position(3)-start_hor)/Ntimes;
            for i=1:Ntimes
                % Plot
                a1=uiaxes(app.experimental);
                a1.Position=[start_hor+(i-1)*d_hor app.experimental.Position(4)-150 d_hor 100];
                data_t=data(:,i);
                data_t=data_t(~isnan(data_t));
                bv=histcounts(data_t,bins)/numel(data_t);
                app.p2d_exp(i,:)=bv;
                loglog(a1,bins(1:end-1),bv,'ok');
                a1.YLim=[10^(-4) 1];
                % Add y-legend only to first plot
                if i>1
                    a1.YTick=[];
                else
                    a1.YLabel.String='Clone fraction';
                end
                t1=uitextarea(app.experimental,'Value',headers{i});
                t1.Position=[start_hor+(i-1)*d_hor app.experimental.Position(4)-50 80 20];
                % store figure handle
                app.exp_fig_axis(i)=a1;
                app.exp_fig_labels(i)=t1;
            end
            
            % Get days from headers of experimental data file columns
            app.days=zeros(Ntimes,1);
            for i=1:Ntimes
                cc=0;
                day=str2num(headers{i}(end-cc:end));
                while ~isempty(day)
                    app.days(i)=day; 
                    cc=cc+1;
                    day=str2num(headers{i}(end-cc:end));
                end
            end
            
            % Show filename of loaded data
            tx1 = uitextarea(app.experimental);
            tx1.Value = app.file; 
            tx1.Position=app.loadexp.Position;
            tx1.Position(2) = app.loadexp.Position(2) - 60;
            tx1.Position(4) = 40;
            
            % Add numerical data to experimental data figurezs
            update_num_in_expfig(app);
            
            % Enable inference if number of fixed parameters <3
            if app.Nfixed<3
                app.Buttoninference.Enable='on';
            end

            % Enable export of experimental data
            app.Buttonexportexp.Enable='on';
        end

        % Value changed function: Sliderlambda
        function SliderlambdaValueChanged(app, event)
            app.lambda = app.Sliderlambda.Value;
            find_discrete(app,'lambda');
            app.Sliderlambda.Value = app.lambda;
            update_numfig(app);
            update_num_in_expfig(app);
        end

        % Value changed function: Sliderh
        function SliderhValueChanged(app, event)
            app.h = app.Sliderh.Value;
            find_discrete(app,'h');
            app.Sliderh.Value = app.h;
            update_numfig(app);     
            update_num_in_expfig(app);
        end

        % Value changed function: Slidert
        function SlidertValueChanged(app, event)
            app.t = app.Slidert.Value;
            find_discrete(app,'t');
            app.Slidert.Value = app.t;
            update_numfig(app);
        end

        % Value changed function: Slidera
        function SlideraValueChanged(app, event)
            app.a = app.Slidera.Value;
            find_discrete(app,'a');
            app.Slidera.Value = app.a;
            update_numfig(app);   
            update_num_in_expfig(app);
        end

        % Button pushed function: Buttoninference
        function ButtoninferenceButtonPushed(app, event)
            %% Operations to execute after the "parameter inference" button is pressed.
            
            % Find all unique parameter combinations
            [Upar_combi,~,Uid]=unique([app.num_data.lambda app.num_data.h app.num_data.a],'rows');
            
            % Filter fixed parameters by binary array "select"
            select=[];
            if app.fixa
                select = app.num_data.a == app.a;
            end
            if app.fixh
                if isempty(select)
                    select = app.num_data.h== app.h;
                else
                    select = (app.num_data.h == app.h & select);
                end
            end
            if app.fixlambda
                if isempty(select)
                    select = app.num_data.lambda == app.lambda;
                else
                    select = (app.num_data.lambda == app.lambda & select);
                end
            end
            % If no parameters were fixed, select is empty, convert to  array of ones to include all parameter combinations
            if isempty(select)
                select=ones(size(Uid));
            end
            
            % Initialize arrays
            Nu=size(Upar_combi,1);
            LS=zeros(Nu,1);
            R2=zeros(Nu,1);
            Nbins=size(app.num_data.p2d,2);
            Ndays=size(app.days,1);
            
            % Get and normalize experimental data
            p2d_exp_loc = app.p2d_exp';
            p2d_exp_loc = p2d_exp_loc(2:end,:)./(ones(size(p2d_exp_loc(2:end,:),1),1)*sum(p2d_exp_loc(2:end,:)) );
            
            % Loop through numerical data
            for i=1:Nu
                ids = (Uid == i & select==1);
                if sum(ids)>0
                    p2d_num=zeros(Nbins,Ndays);
                    for i2=1:Ndays
                        id=( ids & app.num_data.t == app.days(i2) ); 
                        p2d_num(:,i2)=app.num_data.p2d(id,:);
                    end
                    p2d_num = p2d_num(2:end,:)./(ones(size(p2d_num(2:end,:),1),1)*sum(p2d_num(2:end,:)));
                    LS(i)=sum(sum( ((p2d_exp_loc - p2d_num) ).^2 ));
                    R2(i)=1-LS(i)/( sum(sum( (p2d_exp_loc - mean2(p2d_exp_loc)).^2 )) );
                end
            end
            LS(LS==0)=max(LS)+1;
                        
            % If present, remove text outputs of previous inference
            if ~isempty(app.text_inference)
                delete(app.text_inference)
                app.text_inference=gobjects(0);
            end
            
            % Display general best fit in textarea...
            tx1 = uitextarea(app.Inference);
            tx1.Position = [app.Buttoninference.Position(1) app.Buttoninference.Position(2)-82 app.Buttoninference.Position(3) 80];
            message = sprintf('Best fit parameters: \n');
            message = sprintf('%s lambda = %.2f \n', message, Upar_combi(LS==min(LS),1) );
            message = sprintf('%s h = %.2f \n', message, Upar_combi(LS==min(LS),2) );
            message = sprintf('%s a = %.2f \n', message, Upar_combi(LS==min(LS),3) );
            message = sprintf('%s R^2 = %.2f', message, R2(LS==min(LS)) );
            tx1.Value = message;
            tx1.Editable='off'; 
            tx1.BackgroundColor='g';
            app.text_inference(1)=tx1;
            
            % Save best fit parameters as new (global) parameter values
            app.lambda = Upar_combi(LS==min(LS),1);
            app.h = Upar_combi(LS==min(LS),2);
            app.a = Upar_combi(LS==min(LS),3);
            
            % Plot results:  initialize (scatter) heatmap, e.g. lambda vs. h
            a1=uiaxes(app.Inference);
            panel_w=app.Inference.Position(3);
            panel_h=app.Inference.Position(4);
            a1.Position=[round(panel_w*0.1) panel_h*0.02 round(panel_w*0.8) panel_h*0.6];
           
            % IF no parameter is fixed, plot heatmap with best fit for (lambda,h), looping over a.  
            if app.Nfixed==0                           
                [Upar_combi,~,Uid]=unique([app.num_data.lambda app.num_data.h],'rows');
                Nu=size(Upar_combi,1);
                LS=zeros(Nu,1);
                % Loop through all (lambda, h) combinations
                for i=1:Nu
                    as=app.num_data.a(Uid==i,:);
                    Ua=unique(as);
                    Na=numel(Ua);
                    % Loop through all a values for specific (lambda, h) combination
                    for i0=1:Na
                        p2d_num=zeros(Nbins,Ndays);
                        for i2=1:Ndays
                            id=(Uid==i & app.num_data.a==Ua(i0) & app.num_data.t == app.days(i2));
                            p2d_num(:,i2)=app.num_data.p2d(id,:);
                        end
                        p2d_num = p2d_num(2:end,:)./(ones(size(p2d_num(2:end,:),1),1)*sum(p2d_num(2:end,:)));
                        LStemp=sum(sum( (p2d_exp_loc - p2d_num).^2  ));
                        LS(i)=max(LS(i),1/LStemp);
                    end
                end
                LS=LS/max(LS)*10;                
                % Plot
                scatter(a1,Upar_combi(:,1),Upar_combi(:,2),LS,LS,'Fill');
                a1.XLabel.String='\lambda';
                a1.YLabel.String='h';    
            % If one parameter is fixed, plot other two in heatmap.
            elseif app.Nfixed==1         
                ids= ~(LS==max(LS));
                Upar_combi=Upar_combi(ids,:);
                LS=1./LS(ids);
                LS=LS/max(LS)*10;
                if app.fixa
                    scatter(a1,Upar_combi(:,1),Upar_combi(:,2),LS,LS,'Fill');
                    a1.XLabel.String='\lambda';
                    a1.YLabel.String='h';        
                elseif app.fixh
                    scatter(a1,Upar_combi(:,1),Upar_combi(:,3),LS,LS,'Fill');
                    a1.XLabel.String='\lambda';
                    a1.YLabel.String='a';        
                elseif app.fixlambda
                    scatter(a1,Upar_combi(:,2),Upar_combi(:,3),LS,LS,'Fill');
                    a1.XLabel.String='h';
                    a1.YLabel.String='a';                              
                end
            end      
            
            % Add colorbar to heatmap
            c=colorbar(a1,'North','Position',[0.7 0.1 0.2 0.06]); %[0.7 0.89 0.2 0.06]
            set(c,'Ticks',[min(LS) max(LS)]); %[-9 max(max(log2(clone_hist)))]
            set(c,'TickLabels',{'Bad fit','Good fit'});
            
            % Add best fit numerical data to experimental data figure
            update_num_in_expfig(app);
            
            % Update slider values
            update_slider(app,'lambda');
            update_slider(app,'h');
            update_slider(app,'a');
        end

        % Button pushed function: Buttonexportnum
        function ButtonexportnumButtonPushed(app, event)
            %% Stores the numerical distributions with the current parameter values tab separated text file
            
            % Get pointers to numerical distribution of current lambda,h (and a) values at experimental observation times
            id = (app.num_data.a==app.a & app.num_data.lambda==app.lambda & app.num_data.h==app.h);
            
            % Create table T with in the first two columns the bin starts and ends
            bin_start=(2.^[0:15])';
            bin_end=(2.^[1:16])'-1;
            T=table(bin_start,bin_end);
            
            % Get numerical clone size distributions at days of measurement and store data from each day in separate column 
            ts=sort(unique(app.num_data.t));
            for i=1:size(ts,1)
                var_name=strcat('Day ',num2str(ts(i)));
                T.(var_name)=app.num_data.p2d(id & app.num_data.t==ts(i),:)';
            end
            
            % Create numerical filename from current parameter values and store numerical distribution to text file
            filename=strcat('numerical_distributions_lambda',num2str(app.lambda),'_h',num2str(app.h),'_a',num2str(app.a),'.txt');
            writetable(T,filename,'Delimiter','\t');
        end

        % Button pushed function: Buttonexportexp
        function ButtonexportexpButtonPushed(app, event)
            %% Stores the experimental distributions with the current parameter values tab separated text file
            
            % Create table T with in the first two columns the bin starts and ends, and the data per data in the further columns
            bin_start=(2.^[0:15])';
            bin_end=(2.^[1:16])'-1;
            T=table(bin_start,bin_end);
            for i=1:numel(app.days)
                var_name=strcat('Day ',num2str(app.days(i)));
                T.(var_name)=app.p2d_exp(i,:)';
            end
            
            % Store numerical distribution to text file
            dot_find=strfind(app.file,'.xl');
            filename=strcat(app.file(1:dot_find(end)-1),'_distributions.txt');
            writetable(T,filename,'Delimiter','\t');
        end

        % Value changed function: CheckBoxfixh
        function CheckBoxfixhValueChanged(app, event)
            value = app.CheckBoxfixh.Value;
            app.fixh = value;
            
            % Enable/disable inference depending on number of fixed parameters
            app.Nfixed = app.Nfixed + value*2-1;
            if isempty(app.p2d_exp) || app.Nfixed==3
                app.Buttoninference.Enable='off';
            else
                app.Buttoninference.Enable='on';
            end
        end

        % Value changed function: CheckBoxfixa
        function CheckBoxfixaValueChanged(app, event)
            value = app.CheckBoxfixa.Value;            
            app.fixa =value;
            
            % Enable/disable inference depending on number of fixed parameters 
            app.Nfixed = app.Nfixed + value*2-1;
            if isempty(app.p2d_exp) || app.Nfixed==3
                app.Buttoninference.Enable='off';
            else
                app.Buttoninference.Enable='on';
            end
        end

        % Value changed function: CheckBoxfixlambda
        function CheckBoxfixlambdaValueChanged(app, event)
            value = app.CheckBoxfixlambda.Value; 
            app.fixlambda = value;
            
            % Enable/disable inference depending on number of fixed parameters 
            app.Nfixed = app.Nfixed + value*2-1;
            if isempty(app.p2d_exp) || app.Nfixed==3
                app.Buttoninference.Enable='off';
            else
                app.Buttoninference.Enable='on';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'CloneFit';
            app.experimental = uipanel(app.UIFigure);
            app.experimental.Title = 'Experimental data';
            app.loadexp = uibutton(app.experimental, 'push');
            app.loadexp.ButtonPushedFcn = createCallbackFcn(app, @loadexpButtonPushed, true);
            app.loadexp.Text = 'Load experimental ';
            app.numerical = uipanel(app.UIFigure);
            app.numerical.Title = 'Numerical data';
            app.UIAxesnum = uiaxes(app.numerical);
            xlabel(app.UIAxesnum, 'Clone size')
            ylabel(app.UIAxesnum, 'Clone fraction')
            app.LabelSlider = uilabel(app.numerical);
            app.LabelSlider.Text = 'Time';
            app.Slidert = uislider(app.numerical);
            app.Slidert.ValueChangedFcn = createCallbackFcn(app, @SlidertValueChanged, true);
            app.LabelSlider3 = uilabel(app.numerical);
            app.LabelSlider3.Text = 'h';
            app.Sliderh = uislider(app.numerical);
            app.Sliderh.ValueChangedFcn = createCallbackFcn(app, @SliderhValueChanged, true);
            app.LabelSlider4 = uilabel(app.numerical);
            app.LabelSlider4.Text = 'lambda';
            app.Sliderlambda = uislider(app.numerical);
            app.Sliderlambda.ValueChangedFcn = createCallbackFcn(app, @SliderlambdaValueChanged, true);
            app.Label = uilabel(app.numerical);
            app.Label.Text = 'a';
            app.Slidera = uislider(app.numerical);
            app.Slidera.ValueChangedFcn = createCallbackFcn(app, @SlideraValueChanged, true);
            app.TextArea = uitextarea(app.numerical);
            app.TextArea.Editable = 'off';
            app.TextArea.Enable = 'off';
            app.TextArea.Value = {'Surface '; 'Growth'};
            app.TextArea_2 = uitextarea(app.numerical);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Enable = 'off';
            app.TextArea_2.Value = {'Exponential'; 'Growth'};
            app.Inference = uipanel(app.UIFigure);
            app.Inference.Title = 'Inference';
            app.Buttoninference = uibutton(app.Inference, 'push');
            app.Buttoninference.ButtonPushedFcn = createCallbackFcn(app, @ButtoninferenceButtonPushed, true);
            app.Buttoninference.Text = 'Parameter inference';
            app.Panel2 = uipanel(app.Inference);
            app.CheckBoxfixh = uicheckbox(app.Panel2);
            app.CheckBoxfixh.ValueChangedFcn = createCallbackFcn(app, @CheckBoxfixhValueChanged, true);
            app.CheckBoxfixh.Text = 'Fix h';
            app.CheckBoxfixa = uicheckbox(app.Panel2);
            app.CheckBoxfixa.ValueChangedFcn = createCallbackFcn(app, @CheckBoxfixaValueChanged, true);
            app.CheckBoxfixa.Text = 'Fix a';
            app.CheckBoxfixlambda = uicheckbox(app.Panel2);
            app.CheckBoxfixlambda.ValueChangedFcn = createCallbackFcn(app, @CheckBoxfixlambdaValueChanged, true);
            app.CheckBoxfixlambda.Text = 'Fix lambda';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Export distributions';
            app.Buttonexportnum = uibutton(app.Panel, 'push');
            app.Buttonexportnum.ButtonPushedFcn = createCallbackFcn(app, @ButtonexportnumButtonPushed, true);
            app.Buttonexportnum.Text = 'Export numerical';
            app.Buttonexportexp = uibutton(app.Panel, 'push');
            app.Buttonexportexp.ButtonPushedFcn = createCallbackFcn(app, @ButtonexportexpButtonPushed, true);
            app.Buttonexportexp.Text = 'Export experimental';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CloneFit

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end